classdef QRphotomosaic_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        statusTextArea               matlab.ui.control.TextArea
        statusTextAreaLabel          matlab.ui.control.Label
        photomosaicparametersettingLabel  matlab.ui.control.Label
        errorcorrectionlevelButtonGroup  matlab.ui.container.ButtonGroup
        H30Button                    matlab.ui.control.RadioButton
        Q25Button                    matlab.ui.control.RadioButton
        M15Button                    matlab.ui.control.RadioButton
        L7Button                     matlab.ui.control.RadioButton
        textorURLEditField           matlab.ui.control.EditField
        textorURLEditFieldLabel      matlab.ui.control.Label
        QRphotomosaicButton          matlab.ui.control.Button
        MaxrepeatEditField           matlab.ui.control.NumericEditField
        MaxrepeatEditFieldLabel      matlab.ui.control.Label
        MaxiterationEditField        matlab.ui.control.NumericEditField
        MaxiterationEditFieldLabel   matlab.ui.control.Label
        photomosaicButton            matlab.ui.control.Button
        visualsizeButtonGroup        matlab.ui.container.ButtonGroup
        x128Button_visualsize        matlab.ui.control.RadioButton
        x64Button_visualsize         matlab.ui.control.RadioButton
        x32Button_visualsize         matlab.ui.control.RadioButton
        x16Button_visualsize         matlab.ui.control.RadioButton
        x8Button_visualsize          matlab.ui.control.RadioButton
        selectButton_workspace       matlab.ui.control.Button
        workspaceEditField           matlab.ui.control.EditField
        workspaceEditFieldLabel      matlab.ui.control.Label
        preprocessButton             matlab.ui.control.Button
        gridsizeButtonGroup          matlab.ui.container.ButtonGroup
        x128Button_tilesize          matlab.ui.control.RadioButton
        x64Button_tilesize           matlab.ui.control.RadioButton
        x32Button_tilesize           matlab.ui.control.RadioButton
        x16Button_tilesize           matlab.ui.control.RadioButton
        x8Button_tilesize            matlab.ui.control.RadioButton
        selectButton_tile            matlab.ui.control.Button
        selectButton_target          matlab.ui.control.Button
        targetimageEditField         matlab.ui.control.EditField
        targetimageEditFieldLabel    matlab.ui.control.Label
        tiledirectoryEditField       matlab.ui.control.EditField
        tiledirectoryEditFieldLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectButton_tile
        function selectButton_tilePushed(app, event)
            path = uigetdir();
            str = path;
            app.tiledirectoryEditField.Value = str;
        end

        % Value changed function: tiledirectoryEditField
        function tiledirectoryEditFieldValueChanged(app, event)
            % value = app.tiledirectoryEditField.Value;
        end

        % Button pushed function: selectButton_target
        function selectButton_targetPushed(app, event)
            [file, path] = uigetfile({ '*.jpg' ; '*.png' ; '*.jpeg'});
            str = [path file];
            app.targetimageEditField.Value = str;            
        end

        % Value changed function: targetimageEditField
        function targetimageEditFieldValueChanged(app, event)
            % value = app.targetimageEditField.Value;
        end

        % Selection changed function: gridsizeButtonGroup
        function gridsizeButtonGroupSelectionChanged(app, event)
            % selectedButton = app.gridsizeButtonGroup.SelectedObject;
        end

        % Button pushed function: preprocessButton
        function preprocessButtonPushed(app, event)

            addpath('./functions/');
            tile_dir = [app.tiledirectoryEditField.Value '/'];
            work_dir = app.workspaceEditField.Value;

            if app.x8Button_tilesize.Value == 1
                grid_size = 8;
            elseif app.x16Button_tilesize.Value == 1
                grid_size = 16;
            elseif app.x32Button_tilesize.Value == 1
                grid_size = 32;
            elseif app.x64Button_tilesize.Value == 1
                grid_size = 64;
            elseif app.x128Button_tilesize.Value == 1
                grid_size = 128;
            else
                grid_size = 8;
            end

            if app.x8Button_visualsize.Value == 1
                visual_size = 8;
            elseif app.x16Button_visualsize.Value == 1
                visual_size = 16;
            elseif app.x32Button_visualsize.Value == 1
                visual_size = 32;
            elseif app.x64Button_visualsize.Value == 1
                visual_size = 64;
            elseif app.x128Button_visualsize.Value == 1
                visual_size = 128;
            else
                visual_size = 8;
            end

            if grid_size ~= visual_size
                tileResize(work_dir, tile_dir, grid_size);
                tileResize(work_dir, tile_dir, visual_size);
            else
                tileResize(work_dir, tile_dir, grid_size);
            end

            lumin_base = 50;
            sigma = 30;

            tileClustering(work_dir, tile_dir, grid_size, lumin_base, sigma);
            tileClustering(work_dir, tile_dir, visual_size, lumin_base, sigma);

            S = ' Preprocess finished ! ';
            app.statusTextArea.Value = S;

        end

        % Button pushed function: selectButton_workspace
        function selectButton_workspacePushed(app, event)
            path = uigetdir();
            str = path;
            app.workspaceEditField.Value = str; 
        end

        % Value changed function: workspaceEditField
        function workspaceEditFieldValueChanged(app, event)
            % value = app.workspaceEditField.Value;
        end

        % Selection changed function: visualsizeButtonGroup
        function visualsizeButtonGroupSelectionChanged(app, event)
            % selectedButton = app.visualsizeButtonGroup.SelectedObject;
        end

        % Value changed function: MaxiterationEditField
        function MaxiterationEditFieldValueChanged(app, event)
            % value = app.MaxiterationEditField.Value;
        end

        % Button pushed function: photomosaicButton
        function photomosaicButtonPushed(app, event)

            addpath('./functions/');
            work_dir = app.workspaceEditField.Value;
            trgt_path = app.targetimageEditField.Value;

            % grid size
            if app.x8Button_tilesize.Value == 1
                grid_size = 8;
            elseif app.x16Button_tilesize.Value == 1
                grid_size = 16;
            elseif app.x32Button_tilesize.Value == 1
                grid_size = 32;
            elseif app.x64Button_tilesize.Value == 1
                grid_size = 64;
            elseif app.x128Button_tilesize.Value == 1
                grid_size = 128;
            else
                grid_size = 8;
            end

            % visual size
            if app.x8Button_visualsize.Value == 1
                visual_size = 8;
            elseif app.x16Button_visualsize.Value == 1
                visual_size = 16;
            elseif app.x32Button_visualsize.Value == 1
                visual_size = 32;
            elseif app.x64Button_visualsize.Value == 1
                visual_size = 64;
            elseif app.x128Button_visualsize.Value == 1
                visual_size = 128;
            else
                visual_size = 8;
            end            

            max_iter = app.MaxiterationEditField.Value;
            max_repeat = app.MaxrepeatEditField.Value;
            alpha = 0.75;

            targetResize(work_dir, trgt_path, grid_size);
            photomosatics(work_dir, grid_size, visual_size, max_iter, max_repeat, alpha);            
            
            S = sprintf(' Photomosaic finished ! \n Result is at %s/photomosatics/photomosatics.jpg', work_dir);
            app.statusTextArea.Value = S;

        end

        % Value changed function: MaxrepeatEditField
        function MaxrepeatEditFieldValueChanged(app, event)
            % value = app.MaxrepeatEditField.Value;
        end

        % Button pushed function: QRphotomosaicButton
        function QRphotomosaicButtonPushed(app, event)

            addpath('./functions/');
            txt = app.textorURLEditField.Value;
            work_dir = app.workspaceEditField.Value;
            bkgd_path = app.targetimageEditField.Value;  

            if app.H30Button.Value == 1
                ecc = 'H';
            elseif app.Q25Button.Value == 1
                ecc = 'Q';
            elseif app.M15Button.Value == 1
                ecc = 'M';
            elseif app.L7Button.Value == 1
                ecc = 'L';
            else
                ecc = 'M';
            end 

            if app.x8Button_tilesize.Value == 1
                grid_size = 8;
            elseif app.x16Button_tilesize.Value == 1
                grid_size = 16;
            elseif app.x32Button_tilesize.Value == 1
                grid_size = 32;
            elseif app.x64Button_tilesize.Value == 1
                grid_size = 64;
            elseif app.x128Button_tilesize.Value == 1
                grid_size = 128;
            else
                grid_size = 8;
            end
            
            if app.x8Button_visualsize.Value == 1
                visual_size = 8;
            elseif app.x16Button_visualsize.Value == 1
                visual_size = 16;
            elseif app.x32Button_visualsize.Value == 1
                visual_size = 32;
            elseif app.x64Button_visualsize.Value == 1
                visual_size = 64;
            elseif app.x128Button_visualsize.Value == 1
                visual_size = 128;
            else
                visual_size = 8;
            end   

            max_iter = app.MaxiterationEditField.Value;
            max_repeat = app.MaxrepeatEditField.Value;
            alpha = 0.75;

            QRPhotomosatics(work_dir, bkgd_path, grid_size, visual_size, txt, ecc, max_iter, max_repeat, alpha);

            S = sprintf(' QRcode Photomosaic finished !	\n Result is at %s/QRphotomosatics/QRphotomosatics.jpg', work_dir);
            app.statusTextArea.Value = S;

        end

        % Selection changed function: errorcorrectionlevelButtonGroup
        function errorcorrectionlevelButtonGroupSelectionChanged(app, event)
            % selectedButton = app.errorcorrectionlevelButtonGroup.SelectedObject;
        end

        % Value changed function: statusTextArea
        function statusTextAreaValueChanged(app, event)
            % value = app.statusTextArea.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.tiledirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.tiledirectoryEditFieldLabel.Text = 'tile directory';
            app.tiledirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.tiledirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @tiledirectoryEditFieldValueChanged, true);
            app.targetimageEditFieldLabel = uilabel(app.UIFigure);
            app.targetimageEditFieldLabel.Text = 'target image';
            app.targetimageEditField = uieditfield(app.UIFigure, 'text');
            app.targetimageEditField.ValueChangedFcn = createCallbackFcn(app, @targetimageEditFieldValueChanged, true);
            app.selectButton_target = uibutton(app.UIFigure, 'push');
            app.selectButton_target.ButtonPushedFcn = createCallbackFcn(app, @selectButton_targetPushed, true);
            app.selectButton_target.Text = 'select';
            app.selectButton_tile = uibutton(app.UIFigure, 'push');
            app.selectButton_tile.ButtonPushedFcn = createCallbackFcn(app, @selectButton_tilePushed, true);
            app.selectButton_tile.Text = 'select';
            app.gridsizeButtonGroup = uibuttongroup(app.UIFigure);
            app.gridsizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @gridsizeButtonGroupSelectionChanged, true);
            app.gridsizeButtonGroup.Title = 'grid size';
            app.x8Button_tilesize = uiradiobutton(app.gridsizeButtonGroup);
            app.x8Button_tilesize.Text = '8 x 8';
            app.x8Button_tilesize.Value = true;
            app.x16Button_tilesize = uiradiobutton(app.gridsizeButtonGroup);
            app.x16Button_tilesize.Text = '16 x 16';
            app.x32Button_tilesize = uiradiobutton(app.gridsizeButtonGroup);
            app.x32Button_tilesize.Text = '32 x 32';
            app.x64Button_tilesize = uiradiobutton(app.gridsizeButtonGroup);
            app.x64Button_tilesize.Text = '64 x 64';
            app.x128Button_tilesize = uiradiobutton(app.gridsizeButtonGroup);
            app.x128Button_tilesize.Text = '128 x 128';
            app.preprocessButton = uibutton(app.UIFigure, 'push');
            app.preprocessButton.ButtonPushedFcn = createCallbackFcn(app, @preprocessButtonPushed, true);
            app.preprocessButton.Text = 'preprocess';
            app.workspaceEditFieldLabel = uilabel(app.UIFigure);
            app.workspaceEditFieldLabel.Text = 'workspace';
            app.workspaceEditField = uieditfield(app.UIFigure, 'text');
            app.workspaceEditField.ValueChangedFcn = createCallbackFcn(app, @workspaceEditFieldValueChanged, true);
            app.selectButton_workspace = uibutton(app.UIFigure, 'push');
            app.selectButton_workspace.ButtonPushedFcn = createCallbackFcn(app, @selectButton_workspacePushed, true);
            app.selectButton_workspace.Text = 'select';
            app.visualsizeButtonGroup = uibuttongroup(app.UIFigure);
            app.visualsizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @visualsizeButtonGroupSelectionChanged, true);
            app.visualsizeButtonGroup.Title = 'visual size';
            app.x8Button_visualsize = uiradiobutton(app.visualsizeButtonGroup);
            app.x8Button_visualsize.Text = '8 x 8';
            app.x8Button_visualsize.Value = true;
            app.x16Button_visualsize = uiradiobutton(app.visualsizeButtonGroup);
            app.x16Button_visualsize.Text = '16 x 16';
            app.x32Button_visualsize = uiradiobutton(app.visualsizeButtonGroup);
            app.x32Button_visualsize.Text = '32 x 32';
            app.x64Button_visualsize = uiradiobutton(app.visualsizeButtonGroup);
            app.x64Button_visualsize.Text = '64 x 64';
            app.x128Button_visualsize = uiradiobutton(app.visualsizeButtonGroup);
            app.x128Button_visualsize.Text = '128 x 128';
            app.photomosaicButton = uibutton(app.UIFigure, 'push');
            app.photomosaicButton.ButtonPushedFcn = createCallbackFcn(app, @photomosaicButtonPushed, true);
            app.photomosaicButton.Text = 'photomosaic';
            app.MaxiterationEditFieldLabel = uilabel(app.UIFigure);
            app.MaxiterationEditFieldLabel.Text = 'Max iteration';
            app.MaxiterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxiterationEditField.ValueDisplayFormat = '%.0f';
            app.MaxiterationEditField.ValueChangedFcn = createCallbackFcn(app, @MaxiterationEditFieldValueChanged, true);
            app.MaxiterationEditField.Value = 100000;
            app.MaxrepeatEditFieldLabel = uilabel(app.UIFigure);
            app.MaxrepeatEditFieldLabel.Text = 'Max repeat';
            app.MaxrepeatEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxrepeatEditField.ValueDisplayFormat = '%.0f';
            app.MaxrepeatEditField.ValueChangedFcn = createCallbackFcn(app, @MaxrepeatEditFieldValueChanged, true);
            app.MaxrepeatEditField.Value = 100;
            app.QRphotomosaicButton = uibutton(app.UIFigure, 'push');
            app.QRphotomosaicButton.ButtonPushedFcn = createCallbackFcn(app, @QRphotomosaicButtonPushed, true);
            app.QRphotomosaicButton.Text = 'QR photomosaic';
            app.textorURLEditFieldLabel = uilabel(app.UIFigure);
            app.textorURLEditFieldLabel.Text = 'text or URL';
            app.textorURLEditField = uieditfield(app.UIFigure, 'text');
            app.errorcorrectionlevelButtonGroup = uibuttongroup(app.UIFigure);
            app.errorcorrectionlevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @errorcorrectionlevelButtonGroupSelectionChanged, true);
            app.errorcorrectionlevelButtonGroup.Title = 'error correction level';
            app.L7Button = uiradiobutton(app.errorcorrectionlevelButtonGroup);
            app.L7Button.Text = 'L (7%)';
            app.M15Button = uiradiobutton(app.errorcorrectionlevelButtonGroup);
            app.M15Button.Text = 'M (15%)';
            app.M15Button.Value = true;
            app.Q25Button = uiradiobutton(app.errorcorrectionlevelButtonGroup);
            app.Q25Button.Text = 'Q (25%)';
            app.H30Button = uiradiobutton(app.errorcorrectionlevelButtonGroup);
            app.H30Button.Text = 'H (30%)';
            app.photomosaicparametersettingLabel = uilabel(app.UIFigure);
            app.photomosaicparametersettingLabel.Text = 'photomosaic parameter setting';
            app.statusTextAreaLabel = uilabel(app.UIFigure);
            app.statusTextAreaLabel.Text = 'status';
            app.statusTextArea = uitextarea(app.UIFigure);
            app.statusTextArea.ValueChangedFcn = createCallbackFcn(app, @statusTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QRphotomosaic_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end